classdef BondPortfolioDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RiskDashboardTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        InputParametersPanel            matlab.ui.container.Panel
        SimulationsSliderLabel          matlab.ui.control.Label
        SimulationsSlider               matlab.ui.control.Slider
        ValuationDateEditFieldLabel     matlab.ui.control.Label
        ValuationDateEditField          matlab.ui.control.EditField
        ProbabilityLabel                matlab.ui.control.Label
        ProbabilityEditField            matlab.ui.control.EditField
        RunRiskAnalysisButton           matlab.ui.control.Button
        InterestRatesPanel              matlab.ui.container.Panel
        zeroRatesTable                  matlab.ui.control.Table
        RiskMeasuresPanel               matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ExpectedLossEditField           matlab.ui.control.EditField
        ExpectedLossEditFieldLabel      matlab.ui.control.Label
        ValueatRiskEditField            matlab.ui.control.EditField
        ValueatRiskEditFieldLabel       matlab.ui.control.Label
        CVaREditField                   matlab.ui.control.EditField
        CVaREditFieldLabel              matlab.ui.control.Label
        StdDeviationEditField           matlab.ui.control.EditField
        StdDeviationEditFieldLabel      matlab.ui.control.Label
        CurrentValueEditField           matlab.ui.control.EditField
        CurrentValueEditFieldLabel      matlab.ui.control.Label
        BondELPlot                      matlab.ui.control.UIAxes
        CounterpartyPlot                matlab.ui.control.UIAxes
        LossPlot                        matlab.ui.control.UIAxes
        ScenariosTab                    matlab.ui.container.Tab
        ScenariosTable                  matlab.ui.control.Table
        InputsBondPortfolioTab          matlab.ui.container.Tab
        PortfolioTable                  matlab.ui.control.Table
        SelectPorfolioButton            matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        BondPortfolioFile               matlab.ui.control.EditField
        InputsCreditDataTab             matlab.ui.container.Tab
        CreditTable                     matlab.ui.control.Table
        SelectCreditDataButton          matlab.ui.control.Button
        FileEditFieldLabel_2            matlab.ui.control.Label
        CreditFile                      matlab.ui.control.EditField
        InputsMigrationsDataTab         matlab.ui.container.Tab
        MigrationsTable                 matlab.ui.control.Table
        SelectMigrationsDataButton      matlab.ui.control.Button
        FileEditFieldLabel_5            matlab.ui.control.Label
        MigrationsDataFile              matlab.ui.control.EditField
        TransitionTable                 matlab.ui.control.Table
        TransitionMatrixPanel           matlab.ui.container.Panel
        InputsFactorExposuresTab        matlab.ui.container.Tab
        FactorExposureTable             matlab.ui.control.Table
        SelectFactorExposureButton      matlab.ui.control.Button
        FileEditFieldLabel_3            matlab.ui.control.Label
        FactorExposureFile              matlab.ui.control.EditField
        InputsFactorCorrelationsTab     matlab.ui.container.Tab
        FactorCorrelationsTable         matlab.ui.control.Table
        SelectFactorCorrelationsButton  matlab.ui.control.Button
        FileEditFieldLabel_4            matlab.ui.control.Label
        FactorCorrelationsFile          matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function populateDefaultData(app,fileList)
            warning off
            app.BondPortfolioFile.Value = fileList(1);
            app.PortfolioTable.Data = readtable(app.BondPortfolioFile.Value);
            app.CreditFile.Value = fileList(2);
            app.CreditTable.Data = readtable(app.CreditFile.Value);
            app.MigrationsDataFile.Value = fileList(3);
            app.MigrationsTable.Data = readtable(app.MigrationsDataFile.Value);
            app.FactorCorrelationsFile.Value = fileList(4);
            app.FactorCorrelationsTable.Data = readtable(app.FactorCorrelationsFile.Value);
            app.FactorExposureFile.Value = fileList(5);
            app.FactorExposureTable.Data = readtable(app.FactorExposureFile.Value);
            
            app.zeroRatesTable.Data = [3.60	3.65	3.72	4.10	5.55	6.05	15.05;
                    4.17	4.22	4.32	4.67	6.02	7.02	15.02;
                    4.73	4.78	4.93	5.25	6.78	8.03	14.03;
                    5.12	5.17	5.32	5.63	7.27	8.52	13.52];

            updateCalculations(app);
            warning on
        end
        
        function updateCalculations(app)
            warning off
            % credit ratings
            creditTable = app.CreditTable.Data;
            creditTable.Rating = rateBonds(app.CreditTable.Data);
            app.CreditTable.Data = creditTable;
            
            % transition table
            app.TransitionTable.Data = transitionMatrix(table2cell(app.MigrationsTable.Data));
            names = {'AAA','AA','A','BBB','BB', 'B', 'CCC', 'Default'};
            transitionMat = app.TransitionTable.Data;
            heatmap(app.TransitionMatrixPanel,names,names,app.TransitionTable.Data);
            
            % scenarios
            valuationDate = datetime(app.ValuationDateEditField.Value,"InputFormat","MM/dd/yyyy");
            zeroRates = app.zeroRatesTable.Data/100;
            bonds = app.PortfolioTable.Data;
            bonds.Rating = creditTable.Rating;
            bonds.Principal = double(strrep(strrep(string(bonds.Principal),'$',''),',',''));
            bonds.CouponRate = double(string(strrep(bonds.CouponRate,'%','')))/100;
            migrationPrices = migrationScenarios(valuationDate, zeroRates, bonds);
            app.ScenariosTable.Data = [bonds.ID, migrationPrices];
            
            % risk calculations
            VaRLevel = double(string(app.ProbabilityEditField.Value))/100;
            numScenarios = round(app.SimulationsSlider.Value);
            factorExposures = app.FactorExposureTable.Data;
            factorExposures = double(strrep(string(factorExposures{:,2:end}),"%",""))/100;
            factorCorr = app.FactorCorrelationsTable.Data;
            factorCorr = factorCorr{:,2:end};
            portMetrics = calcRisk(VaRLevel, numScenarios, valuationDate, ...
                zeroRates, bonds, migrationPrices, transitionMat, ...
                factorExposures, factorCorr,[app.LossPlot, app.CounterpartyPlot, app.BondELPlot]);
            portMetrics = portMetrics;
            app.ExpectedLossEditField.Value = dispCurrency(portMetrics(1));
            app.ValueatRiskEditField.Value = dispCurrency(portMetrics(2));
            app.CVaREditField.Value = dispCurrency(portMetrics(3));
            app.StdDeviationEditField.Value = dispCurrency(portMetrics(4));
            app.CurrentValueEditField.Value = dispCurrency(portMetrics(5));
            warning on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                fileList = ["bonds.csv"; "credit.csv"; "MigrationHistory.csv";
                    "factorCorrelations.csv"; "factorExposures.csv"];
            end
            populateDefaultData(app,fileList);
        end

        % Button pushed function: SelectPorfolioButton
        function SelectPorfolioButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if file == 0, return, end
            app.BondPortfolioFile.Value = fullfile(path,file);
            app.PortfolioTable.Data = readtable(app.BondPortfolioFile.Value);
        end

        % Button pushed function: SelectCreditDataButton
        function SelectCreditDataButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if file == 0, return, end
            app.CreditFile.Value = fullfile(path,file);
            app.CreditTable.Data = readtable(app.CreditFile.Value);
        end

        % Button pushed function: SelectFactorExposureButton
        function SelectFactorExposureButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if file == 0, return, end
            app.FactorExposureFile.Value = fullfile(path,file);
            app.FactorExposureTable.Data = readtable(app.FactorExposureFile.Value);
        end

        % Button pushed function: SelectFactorCorrelationsButton
        function SelectFactorCorrelationsButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if file == 0, return, end
            app.FactorCorrelationsFile.Value = fullfile(path,file);
            app.FactorCorrelationsTable.Data = readtable(app.FactorCorrelationsFile.Value);
        end

        % Button pushed function: SelectMigrationsDataButton
        function SelectMigrationsDataButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            if file == 0, return, end
            app.MigrationsDataFile.Value = fullfile(path,file);
            app.MigrationsTable.Data = readtable(app.MigrationsDataFile.Value);
        end

        % Button pushed function: RunRiskAnalysisButton
        function RunRiskAnalysisButtonPushed(app, event)
            updateCalculations(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RiskDashboardTab = uitab(app.TabGroup);
            app.RiskDashboardTab.Title = 'Risk Dashboard';
            app.GridLayout = uigridlayout(app.RiskDashboardTab);
            app.InputParametersPanel = uipanel(app.GridLayout);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SimulationsSliderLabel = uilabel(app.InputParametersPanel);
            app.SimulationsSliderLabel.Text = 'Simulations';
            app.SimulationsSlider = uislider(app.InputParametersPanel);
            app.SimulationsSlider.Limits = [0 20000];
            app.SimulationsSlider.MajorTicks = [0 5000 10000 15000 20000];
            app.SimulationsSlider.Value = 10000;
            app.ValuationDateEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ValuationDateEditFieldLabel.Text = 'Valuation Date';
            app.ValuationDateEditField = uieditfield(app.InputParametersPanel, 'text');
            app.ValuationDateEditField.Value = '4/9/2017';
            app.ProbabilityLabel = uilabel(app.InputParametersPanel);
            app.ProbabilityLabel.Text = 'Probability (%)';
            app.ProbabilityEditField = uieditfield(app.InputParametersPanel, 'text');
            app.ProbabilityEditField.Value = '99.0';
            app.RunRiskAnalysisButton = uibutton(app.InputParametersPanel, 'push');
            app.RunRiskAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunRiskAnalysisButtonPushed, true);
            app.RunRiskAnalysisButton.Text = 'Run Risk Analysis';
            app.InterestRatesPanel = uipanel(app.GridLayout);
            app.InterestRatesPanel.Title = 'Interest Rates (%)';
            app.zeroRatesTable = uitable(app.InterestRatesPanel);
            app.zeroRatesTable.ColumnName = {'AAA'; 'AA'; 'A'; 'BBB'; 'BB'; 'B'; 'CCC'};
            app.zeroRatesTable.RowName = {'Year1'; 'Year2'; 'Year3'; 'Year4'};
            app.RiskMeasuresPanel = uipanel(app.GridLayout);
            app.RiskMeasuresPanel.Title = 'Risk Measures';
            app.GridLayout2 = uigridlayout(app.RiskMeasuresPanel);
            app.ExpectedLossEditField = uieditfield(app.GridLayout2, 'text');
            app.ExpectedLossEditFieldLabel = uilabel(app.GridLayout2);
            app.ExpectedLossEditFieldLabel.Text = 'Expected Loss';
            app.ValueatRiskEditField = uieditfield(app.GridLayout2, 'text');
            app.ValueatRiskEditFieldLabel = uilabel(app.GridLayout2);
            app.ValueatRiskEditFieldLabel.Text = 'Value-at-Risk';
            app.CVaREditField = uieditfield(app.GridLayout2, 'text');
            app.CVaREditFieldLabel = uilabel(app.GridLayout2);
            app.CVaREditFieldLabel.Text = 'CVaR';
            app.StdDeviationEditField = uieditfield(app.GridLayout2, 'text');
            app.StdDeviationEditFieldLabel = uilabel(app.GridLayout2);
            app.StdDeviationEditFieldLabel.Text = 'Std. Deviation';
            app.CurrentValueEditField = uieditfield(app.GridLayout2, 'text');
            app.CurrentValueEditFieldLabel = uilabel(app.GridLayout2);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.BondELPlot = uiaxes(app.GridLayout);
            title(app.BondELPlot, 'Title')
            xlabel(app.BondELPlot, 'X')
            ylabel(app.BondELPlot, 'Y')
            zlabel(app.BondELPlot, 'Z')
            app.CounterpartyPlot = uiaxes(app.GridLayout);
            title(app.CounterpartyPlot, 'Title')
            xlabel(app.CounterpartyPlot, 'X')
            ylabel(app.CounterpartyPlot, 'Y')
            zlabel(app.CounterpartyPlot, 'Z')
            app.LossPlot = uiaxes(app.GridLayout);
            title(app.LossPlot, 'Title')
            xlabel(app.LossPlot, 'X')
            ylabel(app.LossPlot, 'Y')
            zlabel(app.LossPlot, 'Z')
            app.ScenariosTab = uitab(app.TabGroup);
            app.ScenariosTab.Title = 'Scenarios';
            app.ScenariosTable = uitable(app.ScenariosTab);
            app.ScenariosTable.ColumnName = {'ID'; 'AAA'; 'AA'; 'A'; 'BBB'; 'BB'; 'B'; 'CCC'; 'Default'};
            app.ScenariosTable.RowName = {};
            app.InputsBondPortfolioTab = uitab(app.TabGroup);
            app.InputsBondPortfolioTab.Title = 'Inputs: Bond Portfolio';
            app.PortfolioTable = uitable(app.InputsBondPortfolioTab);
            app.PortfolioTable.ColumnName = {'ID'; 'Principal'; 'Maturity'; 'Coupon Rate'; 'LGD'; 'Counterparty'; 'Number of Bonds'; 'Industry'; 'Rating'};
            app.PortfolioTable.RowName = {};
            app.SelectPorfolioButton = uibutton(app.InputsBondPortfolioTab, 'push');
            app.SelectPorfolioButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPorfolioButtonPushed, true);
            app.SelectPorfolioButton.Text = 'Select Porfolio';
            app.FileEditFieldLabel = uilabel(app.InputsBondPortfolioTab);
            app.FileEditFieldLabel.Text = 'File:';
            app.BondPortfolioFile = uieditfield(app.InputsBondPortfolioTab, 'text');
            app.BondPortfolioFile.Editable = 'off';
            app.InputsCreditDataTab = uitab(app.TabGroup);
            app.InputsCreditDataTab.Title = 'Inputs: Credit Data';
            app.CreditTable = uitable(app.InputsCreditDataTab);
            app.CreditTable.ColumnName = {'ID'; 'WC_TA'; 'RE_TA'; 'EBIT_TA'; 'MVE_BVTD'; 'S_TA'; 'Industry'; 'Calculated:Ratings'};
            app.CreditTable.RowName = {};
            app.SelectCreditDataButton = uibutton(app.InputsCreditDataTab, 'push');
            app.SelectCreditDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCreditDataButtonPushed, true);
            app.SelectCreditDataButton.Text = 'Select Credit Data';
            app.FileEditFieldLabel_2 = uilabel(app.InputsCreditDataTab);
            app.FileEditFieldLabel_2.Text = 'File:';
            app.CreditFile = uieditfield(app.InputsCreditDataTab, 'text');
            app.CreditFile.Editable = 'off';
            app.InputsMigrationsDataTab = uitab(app.TabGroup);
            app.InputsMigrationsDataTab.Title = 'Inputs: Migrations Data';
            app.MigrationsTable = uitable(app.InputsMigrationsDataTab);
            app.MigrationsTable.ColumnName = {'ID'; 'Date'; 'Rating'};
            app.MigrationsTable.RowName = {};
            app.SelectMigrationsDataButton = uibutton(app.InputsMigrationsDataTab, 'push');
            app.SelectMigrationsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMigrationsDataButtonPushed, true);
            app.SelectMigrationsDataButton.Text = 'Select Migrations Data';
            app.FileEditFieldLabel_5 = uilabel(app.InputsMigrationsDataTab);
            app.FileEditFieldLabel_5.Text = 'File:';
            app.MigrationsDataFile = uieditfield(app.InputsMigrationsDataTab, 'text');
            app.MigrationsDataFile.Editable = 'off';
            app.TransitionTable = uitable(app.InputsMigrationsDataTab);
            app.TransitionTable.ColumnName = {'AAA'; 'AA'; 'A'; 'BBB'; 'BB'; 'B'; 'CCC'; 'Default'};
            app.TransitionTable.RowName = {'AAA'; 'AA'; 'A'; 'BBB'; 'BB'; 'B'; 'CCC'; 'Default'; ''};
            app.TransitionMatrixPanel = uipanel(app.InputsMigrationsDataTab);
            app.TransitionMatrixPanel.Title = 'Transition Matrix';
            app.InputsFactorExposuresTab = uitab(app.TabGroup);
            app.InputsFactorExposuresTab.Title = 'Inputs: Factor Exposures';
            app.FactorExposureTable = uitable(app.InputsFactorExposuresTab);
            app.FactorExposureTable.ColumnName = {'ID'; 'Healthcare'; 'Utilities'; 'Technology'; 'Industrials'; 'Financials'; 'Consumer Goods'; 'Basic Materials'; 'Counsumer Services'; 'Oild & Gas'; 'Telecommunications'; 'Idiosyncratic'};
            app.FactorExposureTable.RowName = {};
            app.SelectFactorExposureButton = uibutton(app.InputsFactorExposuresTab, 'push');
            app.SelectFactorExposureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFactorExposureButtonPushed, true);
            app.SelectFactorExposureButton.Text = 'Select Factor Exposure';
            app.FileEditFieldLabel_3 = uilabel(app.InputsFactorExposuresTab);
            app.FileEditFieldLabel_3.Text = 'File:';
            app.FactorExposureFile = uieditfield(app.InputsFactorExposuresTab, 'text');
            app.FactorExposureFile.Editable = 'off';
            app.InputsFactorCorrelationsTab = uitab(app.TabGroup);
            app.InputsFactorCorrelationsTab.Title = 'Inputs: Factor Correlations';
            app.FactorCorrelationsTable = uitable(app.InputsFactorCorrelationsTab);
            app.FactorCorrelationsTable.ColumnName = {'Factor'; 'Healthcare'; 'Utilities'; 'Technology'; 'Industrials'; 'Financials'; 'Consumer Goods'; 'Basic Materials'; 'Consumer Services'; 'Oil & Gas'; 'Telecommunications'};
            app.FactorCorrelationsTable.RowName = {};
            app.SelectFactorCorrelationsButton = uibutton(app.InputsFactorCorrelationsTab, 'push');
            app.SelectFactorCorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFactorCorrelationsButtonPushed, true);
            app.SelectFactorCorrelationsButton.Text = 'Select Factor Correlations';
            app.FileEditFieldLabel_4 = uilabel(app.InputsFactorCorrelationsTab);
            app.FileEditFieldLabel_4.Text = 'File:';
            app.FactorCorrelationsFile = uieditfield(app.InputsFactorCorrelationsTab, 'text');
            app.FactorCorrelationsFile.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BondPortfolioDashboard(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end